classdef version1_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        L1EditFieldLabel    matlab.ui.control.Label
        L1EditField         matlab.ui.control.NumericEditField
        L2EditFieldLabel    matlab.ui.control.Label
        L2EditField         matlab.ui.control.NumericEditField
        L3EditFieldLabel    matlab.ui.control.Label
        L3EditField         matlab.ui.control.NumericEditField
        L4EditFieldLabel    matlab.ui.control.Label
        L4EditField         matlab.ui.control.NumericEditField
        Button              matlab.ui.control.Button
        ButtonGroup         matlab.ui.container.ButtonGroup
        theta3theta2Button  matlab.ui.control.RadioButton
        theta4theta2Button  matlab.ui.control.RadioButton
        writetoexcelButton  matlab.ui.control.RadioButton
    end


    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            global L1 L2 L3 L4 theta2 theta2_tmp;
            L1=app.L1EditField.Value;
            L2=app.L2EditField.Value;
            L3=app.L3EditField.Value;
            L4=app.L4EditField.Value;
            pi=3.1415926;
            step_coe=0.01;
            Min_theta2=0;
            Max_theta2=2*pi;
            theta2=[0:step_coe:1]*(Max_theta2-Min_theta2)+Min_theta2;
            theta34=zeros(length(theta2),2);
            x0=[1,1];
            for m=1:length(theta2)
                theta2_tmp=theta2(m);
                theta34(m,:)=fsolve(@func_fsolve,x0);
                disp(theta34(m,:));
            end
            if app.theta3theta2Button.Value==true
                plot(theta2,theta34(:,1))
                xlabel('rad')
                ylabel('rad')
            else 
                if app.theta4theta2Button.Value==true
                    plot(theta2,theta34(:,2))
                    xlabel('rad')
                    ylabel('rad')
                else 
                    disp(length(theta2));
                    disp(length(theta34(:,1)));
                    disp(length(theta34(:,2)));
                    T=table(theta2',theta34(:,1),theta34(:,2));
                    writetable(T,date+"theta2-3-4.xlsx");
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.L1EditFieldLabel = uilabel(app.UIFigure);
            app.L1EditFieldLabel.Text = 'L1';
            app.L1EditField = uieditfield(app.UIFigure, 'numeric');
            app.L2EditFieldLabel = uilabel(app.UIFigure);
            app.L2EditFieldLabel.Text = 'L2';
            app.L2EditField = uieditfield(app.UIFigure, 'numeric');
            app.L3EditFieldLabel = uilabel(app.UIFigure);
            app.L3EditFieldLabel.Text = 'L3';
            app.L3EditField = uieditfield(app.UIFigure, 'numeric');
            app.L4EditFieldLabel = uilabel(app.UIFigure);
            app.L4EditFieldLabel.Text = 'L4';
            app.L4EditField = uieditfield(app.UIFigure, 'numeric');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '运行';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = '关系选择器';
            app.theta3theta2Button = uiradiobutton(app.ButtonGroup);
            app.theta3theta2Button.Text = 'theta3-theta2';
            app.theta3theta2Button.Value = true;
            app.theta4theta2Button = uiradiobutton(app.ButtonGroup);
            app.theta4theta2Button.Text = 'theta4-theta2';
            app.writetoexcelButton = uiradiobutton(app.ButtonGroup);
            app.writetoexcelButton.Text = 'write-to-excel';
        end
    end

    methods (Access = public)

        % Construct app
        function app = version1_0

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end